classdef fun1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Coimpare2typeEditField       matlab.ui.control.EditField
        Coimpare2typeEditFieldLabel  matlab.ui.control.Label
        Compare1typeEditField        matlab.ui.control.EditField
        Compare1typeEditFieldLabel   matlab.ui.control.Label
        compare2CheckBox             matlab.ui.control.CheckBox
        compare1CheckBox             matlab.ui.control.CheckBox
        similaritiesButton           matlab.ui.control.Button
        plotdataButton               matlab.ui.control.Button
        loadcoregButton              matlab.ui.control.Button
        CreatemaskButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        x % Description
        y % Description
        sqa % Description
        mqa % Description
        r % Description
        c % Description
        xqa % Description
        yqa % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadcoregButton
        function loadcoregButtonPushed(app, event)
                            % string file_name 
            [file,path] = uigetfile('*.mat')
           
                if strcmp(file ,'a1.mat') == 1
                    load('a1.mat', 'a1')
                    imshow(a1)
                    grid on;
                elseif strcmp(file,'b1.mat') == 1
                    load('b1.mat','a1')
                    imshow(a1)
                    grid on;
                elseif strcmp(file,'c1.mat') == 1
                    load('c1.mat','c1')
                    imshow(c1)
                    grid on;
                elseif strcmp(file,'d1.mat') == 1
                    load('d1.mat','d1')
                    imshow(d1)
                    grid on;
                elseif strcmp(file,'e1.mat') == 1
                    load('e1.mat','e1')
                    [rows, columns, numberOfColorChannels] = size(e1);
                    for row = 1 : 5 : rows
                      e1(row, :) = 0;
                    end
                    for col = 1 : 5 : columns
                      e1(:, col) = 255;
                    end
                    imshow(e1)
                end

        end

        % Button pushed function: CreatemaskButton
        function CreatemaskButtonPushed(app, event)
            [file,path] = uigetfile('*.mat')
            load(file, 'reconstructedData')
            random = drawpolygon;
            Amask = createMask(random);
            figure(2); imshow(Amask)
            
            
                [app.r app.c] = find(Amask == 1)
                L = length(app.r)
                d = []
                for k1 = 1:L
                    d = cat(2, d, squeeze(reconstructedData(app.r(k1),app.c(k1),:)))
                end
            if app.compare1CheckBox.Value == 1
                app.xqa = transpose(d)
            end
            if app.compare2CheckBox.Value == 1
                app.yqa = transpose(d)
            end    
        end

        % Button pushed function: plotdataButton
        function plotdataButtonPushed(app, event)
            [file,path] = uigetfile('*.mat')
            load(file, 'qvals')
            L = length(app.r)
                
            app.sqa = mean(app.xqa,1)
            errred = std(app.xqa)
            figure(3); e2 = errorbar(qvals, app.sqa, errred); 
            e2.Color = 'r'
            hold on;
                
            app.mqa = mean(app.yqa,1)
            errblack = std(app.yqa)
            figure(3); e2 = errorbar(qvals, app.mqa, errblack); 
            e2.Color = 'k'
            
            legend(app.Compare1typeEditField.Value, app.Coimpare2typeEditField.Value);
            figure(3); xlabel('q [1/A]'); ylabel('XRD amplitude [arb]'); title("Spectra Line Graph with Error Bars")

            figure; plot(app.sqa,app.mqa)
            xlabel(app.Compare1typeEditField.Value); ylabel(app.Coimpare2typeEditField.Value); title("Comparison")


        end

        % Button pushed function: similaritiesButton
        function similaritiesButtonPushed(app, event)
            
        end

        % Value changed function: Compare1typeEditField
        function Compare1typeEditFieldValueChanged(app, event)
            value = app.Compare1typeEditField.Value;
            value = input('your name');
        end

        % Value changed function: Coimpare2typeEditField
        function Coimpare2typeEditFieldValueChanged(app, event)
            value = app.Coimpare2typeEditField.Value;
            value = input('your name');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CreatemaskButton = uibutton(app.UIFigure, 'push');
            app.CreatemaskButton.ButtonPushedFcn = createCallbackFcn(app, @CreatemaskButtonPushed, true);
            app.CreatemaskButton.Text = 'Createmask';
            app.loadcoregButton = uibutton(app.UIFigure, 'push');
            app.loadcoregButton.ButtonPushedFcn = createCallbackFcn(app, @loadcoregButtonPushed, true);
            app.loadcoregButton.Text = 'loadcoreg';
            app.plotdataButton = uibutton(app.UIFigure, 'push');
            app.plotdataButton.ButtonPushedFcn = createCallbackFcn(app, @plotdataButtonPushed, true);
            app.plotdataButton.Text = 'plot data';
            app.similaritiesButton = uibutton(app.UIFigure, 'push');
            app.similaritiesButton.ButtonPushedFcn = createCallbackFcn(app, @similaritiesButtonPushed, true);
            app.similaritiesButton.Text = 'similarities';
            app.compare1CheckBox = uicheckbox(app.UIFigure);
            app.compare1CheckBox.Text = 'compare 1';
            app.compare2CheckBox = uicheckbox(app.UIFigure);
            app.compare2CheckBox.Text = 'compare 2';
            app.Compare1typeEditFieldLabel = uilabel(app.UIFigure);
            app.Compare1typeEditFieldLabel.Text = 'Compare 1 type';
            app.Compare1typeEditField = uieditfield(app.UIFigure, 'text');
            app.Compare1typeEditField.ValueChangedFcn = createCallbackFcn(app, @Compare1typeEditFieldValueChanged, true);
            app.Coimpare2typeEditFieldLabel = uilabel(app.UIFigure);
            app.Coimpare2typeEditFieldLabel.Text = 'Coimpare 2 type';
            app.Coimpare2typeEditField = uieditfield(app.UIFigure, 'text');
            app.Coimpare2typeEditField.ValueChangedFcn = createCallbackFcn(app, @Coimpare2typeEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fun1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end