classdef coregsection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        loadcoregButton               matlab.ui.control.Button
        CombinedCoregistrationButton  matlab.ui.control.Button
        GrayMatterBinaryMaskButton    matlab.ui.control.Button
        WhiteMatterBinaryMaskButton   matlab.ui.control.Button
        CancerBinaryMaskButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        file_name % Description
        cancerblock % Description
        whitematterblock % Description
        graymatterblock % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadcoregButton
        function loadcoregButtonPushed(app, event)
                           % string file_name 
            [file,path] = uigetfile('*.mat')
           
                if strcmp(file ,'a1.mat') == 1
                    load('a1.mat', 'a1')
                    imshow(a1)
                elseif strcmp(file,'b1.mat') == 1
                    load('b1.mat','a1')
                    imshow(a1)
                elseif strcmp(file,'c1.mat') == 1
                    load('c1.mat','c1')
                    imshow(c1)
                elseif strcmp(file,'d1.mat') == 1
                    load('d1.mat','d1')
                    imshow(d1)
                elseif strcmp(file,'e1.mat') == 1
                    load('e1.mat','e1')
                    imshow(e1)
                elseif strcmp(file,'gtThyroid.mat') == 1
                    load('gtThyroid.mat','gt')
                    imshow(gt)
                elseif strcmp(file,'cdfr.mat') == 1
                    load('cdfr.mat','b')
                    imshow(b)
                elseif strcmp(file,'new cancer region.mat') == 1
                    load('new cancer region.mat', 'a')
                    imshow(a)
                end   
        end

        % Button pushed function: CancerBinaryMaskButton
        function CancerBinaryMaskButtonPushed(app, event)
 
                random = drawfreehand;
                Amask = createMask(random);
                
                redChannel = 255 * uint8(Amask);
                greenChannel = 0 * ones(size(Amask), 'uint8'); 
                blueChannel = 0 * ones(size(Amask), 'uint8');
                app.cancerblock = cat(3, redChannel, greenChannel, blueChannel);
                %app.cancerblock = [app.cancerblock, app.cancerblock]
                figure (2); imshow(app.cancerblock)

        end

        % Button pushed function: WhiteMatterBinaryMaskButton
        function WhiteMatterBinaryMaskButtonPushed(app, event)
                random = drawfreehand;
                Bmask = createMask(random);

                blueChannel = 0 * uint8(Bmask);
                redChannel = 255 * ones(size(Bmask), 'uint8'); 
                greenChannel = 0 * ones(size(Bmask), 'uint8');
                app.whitematterblock = cat(3, redChannel, greenChannel, blueChannel);
                figure (3); imshow(app.whitematterblock)


        end

        % Button pushed function: GrayMatterBinaryMaskButton
        function GrayMatterBinaryMaskButtonPushed(app, event)
                random = drawfreehand;
                Cmask = createMask(random);

                greenChannel = 0 * uint8(Cmask);
                redChannel = 255 * ones(size(Cmask), 'uint8'); 
                blueChannel = 0 * ones(size(Cmask), 'uint8');
                app.graymatterblock  = cat(3, redChannel, greenChannel, blueChannel);
                figure (4); imshow(app.graymatterblock)

        end

        % Button pushed function: CombinedCoregistrationButton
        function CombinedCoregistrationButtonPushed(app, event)
            poop = [app.cancerblock + app.graymatterblock + app.whitematterblock]
            figure(8); imshow(poop)


            %subplot(1,2,2);
            %figure(11); imshow(app.cancerblock)
           % hold on;
            %subplot(1,2,2);
           % figure(11); imshow(app.graymatterblock)
            %hold on;
            %figure(11); imshow(app.whitematterblock)
           % hold on;

           %       a = imshow(app.cancerblock)
          %  hold on;
           % b = imshow(app.graymatterblock)
           % hold on;
           % c = imshow(app.whitematterblock)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CancerBinaryMaskButton = uibutton(app.UIFigure, 'push');
            app.CancerBinaryMaskButton.ButtonPushedFcn = createCallbackFcn(app, @CancerBinaryMaskButtonPushed, true);
            app.CancerBinaryMaskButton.Text = 'Cancer Binary Mask';
            app.WhiteMatterBinaryMaskButton = uibutton(app.UIFigure, 'push');
            app.WhiteMatterBinaryMaskButton.ButtonPushedFcn = createCallbackFcn(app, @WhiteMatterBinaryMaskButtonPushed, true);
            app.WhiteMatterBinaryMaskButton.Text = 'White Matter Binary Mask';
            app.GrayMatterBinaryMaskButton = uibutton(app.UIFigure, 'push');
            app.GrayMatterBinaryMaskButton.ButtonPushedFcn = createCallbackFcn(app, @GrayMatterBinaryMaskButtonPushed, true);
            app.GrayMatterBinaryMaskButton.Text = 'Gray Matter Binary Mask';
            app.CombinedCoregistrationButton = uibutton(app.UIFigure, 'push');
            app.CombinedCoregistrationButton.ButtonPushedFcn = createCallbackFcn(app, @CombinedCoregistrationButtonPushed, true);
            app.CombinedCoregistrationButton.Text = 'Combined Coregistration';
            app.loadcoregButton = uibutton(app.UIFigure, 'push');
            app.loadcoregButton.ButtonPushedFcn = createCallbackFcn(app, @loadcoregButtonPushed, true);
            app.loadcoregButton.Text = 'load coreg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = coregsection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end