classdef appconnect_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        YvalueEditField       matlab.ui.control.EditField
        YvalueEditFieldLabel  matlab.ui.control.Label
        importumapButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        reduction % Description
        cancerindex % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: importumapButton
        function importumapButtonPushed(app, event)
            import umapFileExchange.*;
            load('recon.mat')
            dataspec = reshape(reconstructedDataCopy, 146*49, [])
            [app.reduction] = run_umap(dataspec)
            figure; plot(app.reduction(:,1), app.reduction(:,2),'.')
            hold on;
            set(app.importumapButton, 'Enable', 'off')
            set(app.importumapButton, 'Text', 'umap loaded ...')
        end

        % Value changed function: YvalueEditField
        function YvalueEditFieldValueChanged(app, event)
            value = app.YvalueEditField.Value
            value_converted = str2double(strsplit(value,','));
            app.cancerindex = [value_converted]
            plot(app.reduction(app.cancerindex,1), app.reduction(app.cancerindex,2), 'rx')
            hold on;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.importumapButton = uibutton(app.UIFigure, 'push');
            app.importumapButton.ButtonPushedFcn = createCallbackFcn(app, @importumapButtonPushed, true);
            app.importumapButton.Text = 'import umap';
            app.YvalueEditFieldLabel = uilabel(app.UIFigure);
            app.YvalueEditFieldLabel.Text = 'Y-value';
            app.YvalueEditField = uieditfield(app.UIFigure, 'text');
            app.YvalueEditField.ValueChangedFcn = createCallbackFcn(app, @YvalueEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appconnect_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end